classdef ManageAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout7          matlab.ui.container.GridLayout
        DoneButton           matlab.ui.control.Button
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        CreateAccountPanel   matlab.ui.container.Panel
        GridLayout6          matlab.ui.container.GridLayout
        CreateAccountButton  matlab.ui.control.Button
        TierDropDown_2       matlab.ui.control.DropDown
        NameEditField_2      matlab.ui.control.EditField
        PasswordEditField_2  matlab.ui.control.EditField
        IDEditField_2        matlab.ui.control.EditField
        ResetPasswordButton  matlab.ui.control.Button
        InformationPanel     matlab.ui.container.Panel
        GridLayout5          matlab.ui.container.GridLayout
        ApplyChangesButton   matlab.ui.control.Button
        TierDropDown         matlab.ui.control.DropDown
        TierDropDownLabel    matlab.ui.control.Label
        NameEditField        matlab.ui.control.EditField
        NameEditFieldLabel   matlab.ui.control.Label
        IDEditField          matlab.ui.control.EditField
        IDEditFieldLabel     matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        DeleteButton         matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        accounts;
        orgAccounts;
        TIER_ADMIN_STRING = 'Admin';   
    end
    
    methods (Access = private)
        
        function InitialLoadAccountList(app)  
            if ~isfile('accounts.mat')                
                disp('Account information is missing or corrupted. Contact the manufacturer.')
                return;
            end

            loadedVar = load('accounts.mat');
            app.orgAccounts = loadedVar.Accounts;
            app.accounts = app.orgAccounts;
            RenewAccountList(app);
        end

        function RenewAccountList(app)
            app.ListBox.Items = fieldnames(app.accounts);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitialLoadAccountList(app);
            ListBoxValueChanged(app, []);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.IDEditField.Value = app.ListBox.Value;
            app.NameEditField.Value = app.accounts.(app.ListBox.Value).Name;
            app.TierDropDown.Value = app.accounts.(app.ListBox.Value).Tier;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            id = app.ListBox.Value;
            tmpAccounts = rmfield(app.accounts, id);
            fieldNames = fieldnames(tmpAccounts);            
            numAdmin = 0;
            for i = 1:length(fieldNames)
                numAdmin = numAdmin + strcmp(tmpAccounts.(fieldNames{i}).Tier, app.TIER_ADMIN_STRING);                
            end
            
            if ~numAdmin
                uialert(app.UIFigure,...
                    'Admin tier shoud be exist at least 1 account',...
                    'Delete error');
                return;
            end

            app.accounts = tmpAccounts;            
            RenewAccountList(app);
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            % TODO : Change name field
            tmpAccounts = app.accounts;
            prevId = app.ListBox.Value;
            newId = app.IDEditField.Value;            
            newName = app.NameEditField.Value;
            [isValid, stateMsg] = ValidateId(newId);
            if ~isValid; uialert(app.UIFigure, stateMsg, 'Renaming error'); return; end

            [isValid, stateMsg] = ValidateName(newName);
            if ~isValid; uialert(app.UIFigure, stateMsg, 'Renaming error'); return; end
            
            tmpAccounts.(prevId).Tier = app.TierDropDown.Value;                
            fieldNames = fieldnames(tmpAccounts);            
            numAdmin = 0;
            for i = 1:length(fieldNames)
                numAdmin = numAdmin + strcmp(tmpAccounts.(fieldNames{i}).Tier, app.TIER_ADMIN_STRING);                
            end
            
            if ~numAdmin
                uialert(app.UIFigure,...
                    'Admin tier shoud be exist at least 1 account',...
                    'Tier changing error');
                return;
            end

            app.accounts = renameStructField(app.accounts, prevId, newId);
            app.accounts.(newId).Tier = app.TierDropDown.Value;
            app.accounts.(newId).Name = newName;
            RenewAccountList(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isequal(app.accounts, app.orgAccounts)
                delete(app);
                return; 
            end
            
            selection = uiconfirm(app.UIFigure, 'Apply changes?', 'Confirmation', ...
                'Options', {'OK', 'No', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3);
            if strcmp(selection, 'Cancel'); return; end
            
            if strcmp(selection, 'No'); delete(app); return; end

            Accounts = app.accounts;
            save("accounts.mat", "Accounts");
            delete(app)
        end

        % Button pushed function: ResetPasswordButton
        function ResetPasswordButtonPushed(app, event)
            PASSWORD_LENGTH = 16;
            id = app.ListBox.Value;
            msg = sprintf('Are you sure you want to reset password for account "%s"?', id);
            selection = uiconfirm(app.UIFigure, msg, 'Confirmation', ...
                'Options', {'OK', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2, ...
                'Icon', 'warning');
            if strcmp(selection, 'Cancel'); return; end

            password = GenerateRandomPassword(PASSWORD_LENGTH);
            clipboard('copy', password);
            hashedPassword = StringToHashedHex(password);
            app.accounts.(id).Password = hashedPassword;
            msg = sprintf('New Password\n\n    %s\n\nis copied to Clipboard', password);
            uiconfirm(app.UIFigure, msg, 'Confirm', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');
        end

        % Button pushed function: CreateAccountButton
        function CreateAccountButtonPushed(app, event)
            id = app.IDEditField_2.Value;
            password = app.PasswordEditField_2.Value;
            name = app.NameEditField_2.Value;
            tier = app.TierDropDown_2.Value;
            [isGenerated, stateMsg, newAccountStruct] = GenerateAccount(id, password, name, tier, app.accounts);
            if ~isGenerated; uialert(app.UIFigure, stateMsg, 'Create account error'); return; end
            
            app.accounts = newAccountStruct;
            RenewAccountList(app);
            app.IDEditField_2.Value = '';
            app.PasswordEditField_2.Value = '';
            msg = sprintf('The account "%s" has been successfully created.', id);
            uiconfirm(app.UIFigure, msg, 'Completed', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            UIFigureCloseRequest(app, []);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.DeleteButton = uibutton(app.GridLayout3, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformationPanel = uipanel(app.GridLayout4);
            app.InformationPanel.Title = 'Information';
            app.GridLayout5 = uigridlayout(app.InformationPanel);
            app.IDEditFieldLabel = uilabel(app.GridLayout5);
            app.IDEditFieldLabel.Text = 'ID :';
            app.IDEditField = uieditfield(app.GridLayout5, 'text');
            app.NameEditFieldLabel = uilabel(app.GridLayout5);
            app.NameEditFieldLabel.Text = 'Name :';
            app.NameEditField = uieditfield(app.GridLayout5, 'text');
            app.TierDropDownLabel = uilabel(app.GridLayout5);
            app.TierDropDownLabel.Text = 'Tier :';
            app.TierDropDown = uidropdown(app.GridLayout5);
            app.TierDropDown.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown.Value = 'Admin';
            app.ApplyChangesButton = uibutton(app.GridLayout5, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ResetPasswordButton = uibutton(app.GridLayout4, 'push');
            app.ResetPasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPasswordButtonPushed, true);
            app.ResetPasswordButton.Text = 'Reset Password';
            app.CreateAccountPanel = uipanel(app.GridLayout4);
            app.CreateAccountPanel.Title = 'Create Account';
            app.GridLayout6 = uigridlayout(app.CreateAccountPanel);
            app.IDEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.IDEditField_2.Placeholder = 'ID';
            app.PasswordEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.PasswordEditField_2.Placeholder = 'Password';
            app.NameEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.NameEditField_2.Placeholder = 'Name';
            app.TierDropDown_2 = uidropdown(app.GridLayout6);
            app.TierDropDown_2.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown_2.Placeholder = 'Select a Tier';
            app.TierDropDown_2.Value = 'Staff';
            app.CreateAccountButton = uibutton(app.GridLayout6, 'push');
            app.CreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccountButtonPushed, true);
            app.CreateAccountButton.Text = 'Create Account';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.DoneButton = uibutton(app.GridLayout7, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccount

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end